classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EBusUIFigure                    matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SchedulerTab                    matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        EnterManuallyTab                matlab.ui.container.Tab
        BusPanel                        matlab.ui.container.Panel
        IDEditFieldLabel                matlab.ui.control.Label
        IDEditField                     matlab.ui.control.NumericEditField
        ChargerEfficiencyEditFieldLabel  matlab.ui.control.Label
        ChargerEfficiencyEditField      matlab.ui.control.NumericEditField
        ChargerPowerkWEditField_2Label  matlab.ui.control.Label
        ChargerPowerkWEditField_2       matlab.ui.control.NumericEditField
        AddBusButton                    matlab.ui.control.Button
        Arrival_timeEditField_2Label    matlab.ui.control.Label
        Arrival_timeEditField_2         matlab.ui.control.EditField
        Depart_timeEditFieldLabel       matlab.ui.control.Label
        Depart_timeEditField            matlab.ui.control.EditField
        RemoveLastButton                matlab.ui.control.Button
        SchedulingPanel                 matlab.ui.container.Panel
        StepSlider_2Label               matlab.ui.control.Label
        StepSlider_2                    matlab.ui.control.Slider
        UnitDropDown_2Label             matlab.ui.control.Label
        UnitDropDown_2                  matlab.ui.control.DropDown
        MaxNumberChargerEditFieldLabel  matlab.ui.control.Label
        MaxNumberChargerEditField       matlab.ui.control.NumericEditField
        PmaxkWEditFieldLabel            matlab.ui.control.Label
        PmaxkWEditField                 matlab.ui.control.NumericEditField
        ScheduleButton                  matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        CheckBox                        matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        UITable_3                       matlab.ui.control.Table
        BatteryParametersPanel_2        matlab.ui.container.Panel
        CapacitykAhEditField_2Label     matlab.ui.control.Label
        CapacitykAhEditField_2          matlab.ui.control.NumericEditField
        TemperatureCEditField_2Label    matlab.ui.control.Label
        TemperatureCEditField_2         matlab.ui.control.NumericEditField
        NrOfSerieCellsEditField_2Label  matlab.ui.control.Label
        NrOfSerieCellsEditField_2       matlab.ui.control.NumericEditField
        CellTypeDropDown_2Label         matlab.ui.control.Label
        CellTypeDropDown_2              matlab.ui.control.DropDown
        NrOfParallelCellsEditField_2Label  matlab.ui.control.Label
        NrOfParallelCellsEditField_2    matlab.ui.control.NumericEditField
        ArrivalSOCEditField_2Label      matlab.ui.control.Label
        ArrivalSOCEditField_2           matlab.ui.control.NumericEditField
        DepartureSOCEditField_2Label    matlab.ui.control.Label
        DepartureSOCEditField_2         matlab.ui.control.NumericEditField
        ImportTab                       matlab.ui.container.Tab
        ImportBusListPanel              matlab.ui.container.Panel
        OKButton_2                      matlab.ui.control.Button
        EnterIPEditField_2Label         matlab.ui.control.Label
        EnterIPEditField_2              matlab.ui.control.EditField
        LoadfileButton_2                matlab.ui.control.Button
        OrLabel_2                       matlab.ui.control.Label
        UITable_2                       matlab.ui.control.Table
        ScheduleButton_2                matlab.ui.control.Button
        OptimiserTab                    matlab.ui.container.Tab
        OptimiseChargersPanel           matlab.ui.container.Panel
        ParameterDropDownLabel          matlab.ui.control.Label
        ParameterDropDown               matlab.ui.control.DropDown
        MinEditFieldLabel               matlab.ui.control.Label
        MinEditField                    matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.NumericEditField
        OptimiseBatteryPanel            matlab.ui.container.Panel
        ParameterDropDown_2Label        matlab.ui.control.Label
        ParameterDropDown_2             matlab.ui.control.DropDown
        MinEditField_2Label             matlab.ui.control.Label
        MinEditField_2                  matlab.ui.control.NumericEditField
        MaxEditField_2Label             matlab.ui.control.Label
        MaxEditField_2                  matlab.ui.control.NumericEditField
        OptimiseBusPanel                matlab.ui.container.Panel
        ParameterDropDown_3Label        matlab.ui.control.Label
        ParameterDropDown_3             matlab.ui.control.DropDown
        MinEditField_3Label             matlab.ui.control.Label
        MinEditField_3                  matlab.ui.control.NumericEditField
        MaxEditField_3Label             matlab.ui.control.Label
        MaxEditField_3                  matlab.ui.control.NumericEditField
        Panel                           matlab.ui.container.Panel
        ChargersCheckBox                matlab.ui.control.CheckBox
        BatteryCheckBox                 matlab.ui.control.CheckBox
        BusCheckBox                     matlab.ui.control.CheckBox
        OptimiseButton                  matlab.ui.control.Button
        SimulatorTab                    matlab.ui.container.Tab
        BatteryParametersPanel          matlab.ui.container.Panel
        CapacitykAhEditFieldLabel       matlab.ui.control.Label
        CapacitykAhEditField            matlab.ui.control.NumericEditField
        TemperatureCEditFieldLabel      matlab.ui.control.Label
        TemperatureCEditField           matlab.ui.control.NumericEditField
        NrOfParallelCellsEditFieldLabel  matlab.ui.control.Label
        NrOfParallelCellsEditField      matlab.ui.control.NumericEditField
        NrOfSerieCellsEditFieldLabel    matlab.ui.control.Label
        NrOfSerieCellsEditField         matlab.ui.control.NumericEditField
        CellTypeDropDownLabel           matlab.ui.control.Label
        CellTypeDropDown                matlab.ui.control.DropDown
        ArrivalSOCEditFieldLabel        matlab.ui.control.Label
        ArrivalSOCEditField             matlab.ui.control.NumericEditField
        DepartureSOCEditFieldLabel      matlab.ui.control.Label
        DepartureSOCEditField           matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        SimulationParametersPanel       matlab.ui.container.Panel
        StepSliderLabel                 matlab.ui.control.Label
        StepSlider                      matlab.ui.control.Slider
        UnitDropDownLabel               matlab.ui.control.Label
        UnitDropDown                    matlab.ui.control.DropDown
        RunSimulationButton             matlab.ui.control.Button
        ChargerPowerkWEditFieldLabel    matlab.ui.control.Label
        ChargerPowerkWEditField         matlab.ui.control.NumericEditField
        EfficiencyLabel                 matlab.ui.control.Label
        EfficiencyEditField             matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        ExternPlotSwitchLabel           matlab.ui.control.Label
        ExternPlotSwitch                matlab.ui.control.Switch
    end


    properties (Access = private)
         Ri_soc_LookUp = [0    0     10      20      30      40      50      60      70      80      90      100;
                    -10   3.27	1.71	1.70	1.68	1.67	1.66	1.63	1.62	1.60	1.58	1.51;
                    0     2.66	1.39	1.38	1.37	1.35	1.34	1.33	1.31	1.30	1.28	1.22;
                    10    2.32	1.21	1.20	1.19	1.18	1.17	1.16	1.15	1.13	1.11	1.06;
                    25    1.98	1.04	1.03	1.02	1.01	1.00	0.99	0.98	0.97	0.95	0.91;
                    40    1.87	0.98	0.97	0.96	0.95	0.95	0.93	0.93	0.92	0.90	0.86;
                    50    1.80	0.94	0.93	0.93	0.92	0.91	0.90	0.89	0.88	0.87	0.83];
        Imax_LookUp =      [   0	-40	-30	-20	-10	0	10	15	25	30	65	85;
                   0	0	0	0	0	0	0	0	0	0	0	0;
                   10	0	0	0	0	0	0	0	0	0	0	0;
                   20	2	2	2	2	2	2	2	2	2	2	0;
                   30	6	6	6	6	6	6	6	6	6	6	0;
                   40	10	10	10	10	10	10	10	10	10	10	0;
                   50	20	20	20	20	20	20	20	20	20	20	0;
                   60	40	40	40	40	40	40	40	40	40	40	0;
                   70	60	60	60	60	60	60	60	60	60	60	0;
                   80	60	60	60	60	60	60	60	60	60	60	0;
                   90	60	60	60	60	60	60	60	60	60	60	0;
                   100	0	0	0	0	0	0	0	0	0	0	0];
        VoltSoc_LookUp =        [0.000 0.021 0.023 0.028 0.036 0.043 0.050 0.059 0.071 0.083 0.105 0.153 0.229 0.305 0.375 0.494 0.582 0.682 0.761 0.864 0.939 0.986 0.995 1.000;
                 2.005 2.120 2.208 2.301 2.389 2.505 2.588 2.708 2.821 2.936 3.031 3.115 3.164 3.189 3.207 3.229 3.235 3.244 3.260 3.271 3.286 3.306 3.352 3.421];
        TempCorrection_LookUp = [0.34 0.57 0.72 0.84 0.91 1.00 1.02 1.03;
                 -30  -20  -10  0    10   25   35   45  ];
    end

    properties (Access = public)
        %General
        ExternalPlot = 0;
        % Sim Tab
        Sim_Battery              % Description
        Sim_dt                   % Description
        Sim_tUnit                % Description
        SimP                     % Description
        Sim_Pefficiency          % Description
        % Schedule Enter Manually Tab
        Sched_Bus                % Description
        Sched_Bus_Array =[]      % Description
        Sched_dt                 % Description
        Sched_tUnit              % Description
        Sched_P                  % Description
        Sched_Pefficiency        % Description
        Sched_nPmax              % Description
        Sched_Pgesmax            % Description
    end 


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            %Simulate
            app.Sim_Battery=aBattery();
            app.Sim_Battery.Ri_soc_LookUp=app.Ri_soc_LookUp;
            app.Sim_Battery.Imax_LookUp=app.Imax_LookUp;
            app.Sim_Battery.VoltSoc_LookUp=app.VoltSoc_LookUp;
            app.Sim_Battery.TempCorrection_LookUp=app.TempCorrection_LookUp;
            app.Sim_tUnit='s';
            app.Sim_dt=1;
            app.Sim_Battery.Temperatur =25;
            %Schedule
            app.Sched_Bus = aBus();
            app.UITable_3.Data=[];
            app.Sched_Bus.ID=0;
            app.Sched_Bus.Battery =aBattery();
            app.Sched_Bus.Battery.Ri_soc_LookUp=app.Ri_soc_LookUp;
            app.Sched_Bus.Battery.Imax_LookUp=app.Imax_LookUp;
            app.Sched_Bus.Battery.VoltSoc_LookUp=app.VoltSoc_LookUp;
            app.Sched_Bus.Battery.TempCorrection_LookUp=app.TempCorrection_LookUp;
            app.Sched_tUnit='s';
            
        end

        % Button pushed function: LoadfileButton_2
        function OpenCSV(app, event)
            [file,path,indx] = uigetfile('*.csv');
            if isequal(file,0)
               disp('User selected Cancel')
            else
               disp(['User selected ', fullfile(path, file),... 
                     ' and filter index: ', num2str(indx)])
            end
        end

        % Value changed function: ArrivalSOCEditField
        function Set_SimStartSOC(app, event)
            value = app.ArrivalSOCEditField.Value;
            app.Sim_Battery.SOC=value;
            
        end

        % Value changed function: DepartureSOCEditField
        function Set_SimEndSOC(app, event)
            value = app.DepartureSOCEditField.Value;
            app.Sim_Battery.endSOC=value;
        end

        % Value changed function: CapacitykAhEditField
        function Set_SimCapacity(app, event)
            value = app.CapacitykAhEditField.Value;
            app.Sim_Battery.Cmax = value*3600; % from kAh to As
        end

        % Value changed function: NrOfParallelCellsEditField
        function Set_SimNrPllCells(app, event)
            value = app.NrOfParallelCellsEditField.Value;
            app.Sim_Battery.NumberOfCellsPll=value;
        end

        % Value changed function: NrOfSerieCellsEditField
        function Set_SimNrSerCells(app, event)
            value = app.NrOfSerieCellsEditField.Value;
            app.Sim_Battery.NumberOfCellsSerie=value;
        end

        % Value changed function: CellTypeDropDown
        function Set_SimCellType(app, event)
            value = app.CellTypeDropDown.Value;
            f = uifigure;
            if strcmp(value,'LiPo')   
                %Set the LookUps
                app.Sim_Battery.Ri_soc_LookUp=app.Ri_soc_LookUp;
                app.Sim_Battery.Imax_LookUp=app.Imax_LookUp;
                app.Sim_Battery.VoltSoc_LookUp=app.VoltSoc_LookUp;
                app.Sim_Battery.TempCorrection_LookUp=app.TempCorrection_LookUp;
            
            elseif strcmp(value,'LTO')
                uialert(f,'Not Supported Yet !','Missing Items');
            elseif strcmp(value,'NMC')
                uialert(f,'Not Supported Yet !','Missing Items');
            end
        end

        % Value changed function: StepSlider
        function Set_SimStep(app, event)
            app.Sim_dt   = app.StepSlider.Value;
            
        end

        % Value changed function: UnitDropDown
        function Set_SimUnit(app, event)
            value = app.UnitDropDown.Value;
            if strcmp(value,'Hours')
                app.Sim_tUnit='h';
            elseif strcmp(value,'Minutes')
                app.Sim_tUnit='min';
            elseif strcmp(value,'Seconds')
                app.Sim_tUnit='s';
            end     
        end

        % Value changed function: ChargerPowerkWEditField
        function Set_SimP(app, event)
           app.SimP = app.ChargerPowerkWEditField.Value;
            
        end

        % Value changed function: EfficiencyEditField
        function Set_SimPeff(app, event)
           app.Sim_Pefficiency = app.EfficiencyEditField.Value;
            
        end

        % Button pushed function: RunSimulationButton
        function Run_Simulation(app, event)
            dt0 =app.Sim_dt;
            p=app.SimP*app.Sim_Pefficiency*10;
            %app.Sim_Battery.Cmax=50*10^3;%As
            %app.Sim_Battery.SOC=45;
            %app.Sim_Battery.endSOC=80;
            %app.Sim_Battery.Temperatur=25;
            %app.Sim_Battery.NumberOfCellsPll=3;
            %app.Sim_Battery.NumberOfCellsSerie=100;
            unit = app.Sim_Battery.ReturnUnit(app.Sim_tUnit);
            un=app.Sim_tUnit;
            withplot = app.ExternalPlot;
            [C,I,P]=app.Sim_Battery.simulateCharge(dt0 ,p,withplot,app.Sim_tUnit);
            [~,i]=size(C);
            t= (0:dt0:dt0*(i-1));
            t=t/unit;
            dt0=dt0/unit;
            ax=app.UIAxes;
            Pmax=max(abs(P));
            p1 = plot(ax,t,-P/1000);%graph type    
            hold(ax,'on');
            p2 = plot(ax,t,Pmax*ones(size(t))/1000);
            legend(ax,[p1 p2],{'Pab','Pmax'})
            title(ax,'P (Charger) Plot')
            xlabel(ax,strcat('Time in ',{' '},un))
            ylabel(ax,'P[kW]')
            xlim(ax,[0 dt*(i-1)])
            ylim(ax,[0 Pmax*1.2/1000])
            hold(ax,'off');
            refreshdata
        end

        % Value changed function: TemperatureCEditField
        function Set_SimTemperature(app, event)
            app.Sim_Battery.Temperatur = app.TemperatureCEditField.Value;
            
        end

        % Value changing function: StepSlider
        function Set_SimStepLabel(app, event)
            app.Label.Text =num2str( fix( event.Value ) );
            
        end

        % Value changed function: ExternPlotSwitch
        function Set_ExternPlot(app, event)
            app.ExternalPlot = app.ExternPlotSwitch.Value;
            
        end

        % Value changed function: ArrivalSOCEditField_2
        function Set_BusArrSOC(app, event)
           app.Sched_Bus.Arrival_SOC = app.ArrivalSOCEditField_2.Value;
           app.Sched_Bus.Battery.SOC=app.Sched_Bus.Arrival_SOC;    
        end

        % Value changed function: DepartureSOCEditField_2
        function Set_BusDepSOC(app, event)
            app.Sched_Bus.Departure_SOC = app.DepartureSOCEditField_2.Value;
            app.Sched_Bus.Battery.endSOC=app.Sched_Bus.Departure_SOC;
        end

        % Value changed function: CapacitykAhEditField_2
        function Set_BusCapacity(app, event)
            app.Sched_Bus.Battery.Cmax = app.CapacitykAhEditField_2.Value*3600;           
        end

        % Value changed function: TemperatureCEditField_2
        function Set_BusTemp(app, event)
            app.Sched_Bus.Battery.Temperatur = app.TemperatureCEditField_2.Value;       
        end

        % Value changed function: NrOfParallelCellsEditField_2
        function Set_BusPllCells(app, event)
            app.Sched_Bus.Battery.NumberOfCellsPll = app.NrOfParallelCellsEditField_2.Value;           
        end

        % Value changed function: NrOfSerieCellsEditField_2
        function Set_BusSerCells(app, event)
            app.Sched_Bus.Battery.NumberOfCellsSerie = app.NrOfSerieCellsEditField_2.Value;        
        end

        % Value changed function: CellTypeDropDown_2
        function Set_BusCellType(app, event)
            value = app.CellTypeDropDown_2.Value;
            f = uifigure;
            if strcmp(value,'LiPo')   
                %Set the LookUps
                app.Sched_Bus.Battery.Ri_soc_LookUp=app.Ri_soc_LookUp;
                app.Sched_Bus.Battery.Imax_LookUp=app.Imax_LookUp;
                app.Sched_Bus.Battery.VoltSoc_LookUp=app.VoltSoc_LookUp;
                app.Sched_Bus.Battery.TempCorrection_LookUp=app.TempCorrection_LookUp;
            elseif strcmp(value,'LTO')
                uialert(f,'Not Supported Yet !','Missing Items');
            elseif strcmp(value,'NMC')
                uialert(f,'Not Supported Yet !','Missing Items');
            end
            
        end

        % Value changed function: IDEditField
        function Set_BusID(app, event)
             value= app.IDEditField.Value;
             app.Sched_Bus.ID=value;
        end

        % Value changed function: Depart_timeEditField
        function Set_BusDepTime(app, event)
            app.Sched_Bus.Departure_time = app.Depart_timeEditField.Value;            
        end

        % Value changed function: ChargerPowerkWEditField_2
        function Set_BusChargerP(app, event)
            app.Sched_P = app.ChargerPowerkWEditField_2.Value*1000; 
        end

        % Value changed function: ChargerEfficiencyEditField
        function Set_BusChargerPeff(app, event)
            app.Sched_Pefficiency = app.ChargerEfficiencyEditField.Value;      
        end

        % Value changed function: UnitDropDown_2
        function Set_BusTimeUnit(app, event)
            value  = app.UnitDropDown_2.Value;
            if strcmp(value,'Hours')
                app.Sched_tUnit='h';
            elseif strcmp(value,'Minutes')
                app.Sched_tUnit='min';
            elseif strcmp(value,'Seconds')
                app.Sched_tUnit='s';
            end
            disp(app.Sched_tUnit)
            
        end

        % Button pushed function: AddBusButton
        function Add_Bus(app, event)
            str1 = int2str(app.Sched_Bus.ID);
            str2 = app.Sched_Bus.Arrival_time;
            str3 = app.Sched_Bus.ChargingStart;
            str4 = app.Sched_Bus.ChargingTime;
            app.UITable_3.Data=[app.UITable_3.Data;{str1,str2,str3,str4}];
            app.Sched_Bus_Array=[app.Sched_Bus_Array;app.Sched_Bus];
            
        end

        % Value changed function: StepSlider_2
        function Set_SchedStep(app, event)
            value = app.StepSlider_2.Value;
            app.Sched_dt=num2str( fix( value ) );
            
        end

        % Value changing function: StepSlider_2
        function Set_SchedStepLabel(app, event)
            app.Label_2.Text =num2str( fix( event.Value ) );
            
        end

        % Value changed function: MaxNumberChargerEditField
        function Set_NmaxChargers(app, event)
            app.Sched_nPmax = app.MaxNumberChargerEditField.Value;
            
        end

        % Value changed function: PmaxkWEditField
        function Set_Ptmax(app, event)
            app.Sched_Pgesmax = app.PmaxkWEditField.Value;
            
        end

        % Value changed function: Arrival_timeEditField_2
        function Set_BusArrTime(app, event)
            value = app.Arrival_timeEditField_2.Value;
            app.Sched_Bus.Arrival_time=value;
            
        end

        % Button pushed function: RemoveLastButton
        function RemoveLast(app, event)
            app.UITable_3.Data(end,:)=[];
            app.Sched_Bus_Array(end)=[];
        end

        % Button pushed function: ScheduleButton
        function Schedule(app, event)
            global iteration dt;
            iteration=0;
            [nr_bus,~]=size(app.Sched_Bus_Array);
            arrtime=datetime('00:00:00','InputFormat','HH:mm:SS');
            deptime=datetime('06:00:00','InputFormat','HH:mm:SS');
            BusArray0=app.Sched_Bus_Array;
            P=app.Sched_P*app.Sched_Pefficiency/100;
            u=app.Sched_tUnit;
            dt=app.Sched_dt;
           
                for i=1:nr_bus
                   Bus=BusArray0(i);
            %                    dt0=100;
            %                    Bus.Battery.Cmax=50*10^3;%As
            %                    Bus.Battery.SOC=45;
            %                    Bus.Battery.endSOC=80;
            %                    Bus.Battery.Temperatur=25;
            %                    Bus.Battery.NumberOfCellsPll=3;
            %                    Bus.Battery.NumberOfCellsSerie=100;
            %                    P=25*10^3;
                   Bus.Battery.IDB=Bus.ID;
                   Bus.CalcP(dt,P,0,u);
                   start_time=abs(etime(datevec(arrtime),datevec(Bus.Arrival_time)));
                   Bus.ChargingStart=start_time;        
                end
            
                %fprintf(1,'There was an error! The message was:\n%s',e.message);
                %f = uifigure;
                %uialert(f,'Time Step  Problem!','Error While Simlating');
            
              
                [Bm,~]=FillBigMatrix(BusArray0,dt,1);
                wC=CalcWorstCase(Bm); 
                [~,sizeBigM ] = size(Bm);
                global goal;
                goal = 'Pmin';
                opt_t0 = Optimise_t0(@Opt_function,BusArray0,dt,arrtime);
                disp('finished opt')
                [Bm,~]=FillBigMatrix(BusArray0,dt,0);
                plot_P(Bm,wC,1);
                for i=1:nr_bus
                     BusArray0(i).ChargingStart=double(opt_t0(i)*dt);
                end  
                newBarplot(BusArray0,dt0,1)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EBusUIFigure = uifigure('Visible', 'off');
            app.EBusUIFigure.Name = 'E Bus';
            app.TabGroup = uitabgroup(app.EBusUIFigure);
            app.SchedulerTab = uitab(app.TabGroup);
            app.SchedulerTab.Title = 'Scheduler';
            app.TabGroup2 = uitabgroup(app.SchedulerTab);
            app.EnterManuallyTab = uitab(app.TabGroup2);
            app.EnterManuallyTab.Title = 'Enter Manually';
            app.BusPanel = uipanel(app.EnterManuallyTab);
            app.BusPanel.Title = 'Bus';
            app.IDEditFieldLabel = uilabel(app.BusPanel);
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.BusPanel, 'numeric');
            app.IDEditField.ValueChangedFcn = createCallbackFcn(app, @Set_BusID, true);
            app.IDEditField.Value = 66;
            app.ChargerEfficiencyEditFieldLabel = uilabel(app.BusPanel);
            app.ChargerEfficiencyEditFieldLabel.Text = 'Charger Efficiency[%]';
            app.ChargerEfficiencyEditField = uieditfield(app.BusPanel, 'numeric');
            app.ChargerEfficiencyEditField.ValueChangedFcn = createCallbackFcn(app, @Set_BusChargerPeff, true);
            app.ChargerEfficiencyEditField.Value = 98;
            app.ChargerPowerkWEditField_2Label = uilabel(app.BusPanel);
            app.ChargerPowerkWEditField_2Label.Text = 'Charger Power[kW]';
            app.ChargerPowerkWEditField_2 = uieditfield(app.BusPanel, 'numeric');
            app.ChargerPowerkWEditField_2.ValueChangedFcn = createCallbackFcn(app, @Set_BusChargerP, true);
            app.ChargerPowerkWEditField_2.Value = 25;
            app.AddBusButton = uibutton(app.BusPanel, 'push');
            app.AddBusButton.ButtonPushedFcn = createCallbackFcn(app, @Add_Bus, true);
            app.AddBusButton.Text = 'Add Bus';
            app.Arrival_timeEditField_2Label = uilabel(app.BusPanel);
            app.Arrival_timeEditField_2Label.Text = 'Arrival_time';
            app.Arrival_timeEditField_2 = uieditfield(app.BusPanel, 'text');
            app.Arrival_timeEditField_2.ValueChangedFcn = createCallbackFcn(app, @Set_BusArrTime, true);
            app.Arrival_timeEditField_2.Value = '00:30';
            app.Depart_timeEditFieldLabel = uilabel(app.BusPanel);
            app.Depart_timeEditFieldLabel.Text = 'Depart_time';
            app.Depart_timeEditField = uieditfield(app.BusPanel, 'text');
            app.Depart_timeEditField.ValueChangedFcn = createCallbackFcn(app, @Set_BusDepTime, true);
            app.Depart_timeEditField.Value = '03:30';
            app.RemoveLastButton = uibutton(app.BusPanel, 'push');
            app.RemoveLastButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveLast, true);
            app.RemoveLastButton.Text = 'RemoveLast';
            app.SchedulingPanel = uipanel(app.EnterManuallyTab);
            app.SchedulingPanel.Title = 'Scheduling';
            app.StepSlider_2Label = uilabel(app.SchedulingPanel);
            app.StepSlider_2Label.Text = 'Step';
            app.StepSlider_2 = uislider(app.SchedulingPanel);
            app.StepSlider_2.Limits = [0 30];
            app.StepSlider_2.ValueChangedFcn = createCallbackFcn(app, @Set_SchedStep, true);
            app.StepSlider_2.ValueChangingFcn = createCallbackFcn(app, @Set_SchedStepLabel, true);
            app.StepSlider_2.Value = 10;
            app.UnitDropDown_2Label = uilabel(app.SchedulingPanel);
            app.UnitDropDown_2Label.Text = 'Unit';
            app.UnitDropDown_2 = uidropdown(app.SchedulingPanel);
            app.UnitDropDown_2.Items = {'Hours', 'Minutes', 'Seconds'};
            app.UnitDropDown_2.ValueChangedFcn = createCallbackFcn(app, @Set_BusTimeUnit, true);
            app.UnitDropDown_2.Value = 'Seconds';
            app.MaxNumberChargerEditFieldLabel = uilabel(app.SchedulingPanel);
            app.MaxNumberChargerEditFieldLabel.Text = 'Max Number Charger';
            app.MaxNumberChargerEditField = uieditfield(app.SchedulingPanel, 'numeric');
            app.MaxNumberChargerEditField.ValueChangedFcn = createCallbackFcn(app, @Set_NmaxChargers, true);
            app.PmaxkWEditFieldLabel = uilabel(app.SchedulingPanel);
            app.PmaxkWEditFieldLabel.Text = 'Pmax [kW]';
            app.PmaxkWEditField = uieditfield(app.SchedulingPanel, 'numeric');
            app.PmaxkWEditField.ValueChangedFcn = createCallbackFcn(app, @Set_Ptmax, true);
            app.ScheduleButton = uibutton(app.SchedulingPanel, 'push');
            app.ScheduleButton.ButtonPushedFcn = createCallbackFcn(app, @Schedule, true);
            app.ScheduleButton.Text = 'Schedule';
            app.Label_2 = uilabel(app.SchedulingPanel);
            app.Label_2.Text = '0';
            app.CheckBox = uicheckbox(app.SchedulingPanel);
            app.CheckBox.Text = '';
            app.CheckBox_2 = uicheckbox(app.SchedulingPanel);
            app.CheckBox_2.Text = '';
            app.UITable_3 = uitable(app.EnterManuallyTab);
            app.UITable_3.ColumnName = {'Bus ID'; 'Arrival'; 'Start Charging'; 'Finish Charging'};
            app.UITable_3.RowName = {};
            app.BatteryParametersPanel_2 = uipanel(app.EnterManuallyTab);
            app.BatteryParametersPanel_2.Title = 'Battery Parameters';
            app.CapacitykAhEditField_2Label = uilabel(app.BatteryParametersPanel_2);
            app.CapacitykAhEditField_2Label.Text = 'Capacity [kAh]';
            app.CapacitykAhEditField_2 = uieditfield(app.BatteryParametersPanel_2, 'numeric');
            app.CapacitykAhEditField_2.ValueChangedFcn = createCallbackFcn(app, @Set_BusCapacity, true);
            app.CapacitykAhEditField_2.Value = 50;
            app.TemperatureCEditField_2Label = uilabel(app.BatteryParametersPanel_2);
            app.TemperatureCEditField_2Label.Text = 'Temperature [°C]';
            app.TemperatureCEditField_2 = uieditfield(app.BatteryParametersPanel_2, 'numeric');
            app.TemperatureCEditField_2.ValueChangedFcn = createCallbackFcn(app, @Set_BusTemp, true);
            app.TemperatureCEditField_2.Value = 25;
            app.NrOfSerieCellsEditField_2Label = uilabel(app.BatteryParametersPanel_2);
            app.NrOfSerieCellsEditField_2Label.Text = 'Nr Of Serie Cells';
            app.NrOfSerieCellsEditField_2 = uieditfield(app.BatteryParametersPanel_2, 'numeric');
            app.NrOfSerieCellsEditField_2.Limits = [1 300];
            app.NrOfSerieCellsEditField_2.ValueChangedFcn = createCallbackFcn(app, @Set_BusSerCells, true);
            app.NrOfSerieCellsEditField_2.Value = 120;
            app.CellTypeDropDown_2Label = uilabel(app.BatteryParametersPanel_2);
            app.CellTypeDropDown_2Label.Text = 'Cell Type';
            app.CellTypeDropDown_2 = uidropdown(app.BatteryParametersPanel_2);
            app.CellTypeDropDown_2.Items = {'LiPo', 'LTO', 'NMC'};
            app.CellTypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @Set_BusCellType, true);
            app.CellTypeDropDown_2.Value = 'LiPo';
            app.NrOfParallelCellsEditField_2Label = uilabel(app.BatteryParametersPanel_2);
            app.NrOfParallelCellsEditField_2Label.Text = 'Nr Of Parallel Cells';
            app.NrOfParallelCellsEditField_2 = uieditfield(app.BatteryParametersPanel_2, 'numeric');
            app.NrOfParallelCellsEditField_2.Limits = [1 100];
            app.NrOfParallelCellsEditField_2.ValueChangedFcn = createCallbackFcn(app, @Set_BusPllCells, true);
            app.NrOfParallelCellsEditField_2.Value = 4;
            app.ArrivalSOCEditField_2Label = uilabel(app.BatteryParametersPanel_2);
            app.ArrivalSOCEditField_2Label.Text = 'Arrival SOC [%]';
            app.ArrivalSOCEditField_2 = uieditfield(app.BatteryParametersPanel_2, 'numeric');
            app.ArrivalSOCEditField_2.Limits = [0 100];
            app.ArrivalSOCEditField_2.ValueChangedFcn = createCallbackFcn(app, @Set_BusArrSOC, true);
            app.ArrivalSOCEditField_2.Value = 20;
            app.DepartureSOCEditField_2Label = uilabel(app.BatteryParametersPanel_2);
            app.DepartureSOCEditField_2Label.Text = 'Departure SOC[%]';
            app.DepartureSOCEditField_2 = uieditfield(app.BatteryParametersPanel_2, 'numeric');
            app.DepartureSOCEditField_2.Limits = [0 100];
            app.DepartureSOCEditField_2.ValueChangedFcn = createCallbackFcn(app, @Set_BusDepSOC, true);
            app.DepartureSOCEditField_2.Value = 80;
            app.ImportTab = uitab(app.TabGroup2);
            app.ImportTab.Title = 'Import';
            app.ImportBusListPanel = uipanel(app.ImportTab);
            app.ImportBusListPanel.Title = 'Import Bus List';
            app.OKButton_2 = uibutton(app.ImportBusListPanel, 'push');
            app.OKButton_2.Text = 'OK';
            app.EnterIPEditField_2Label = uilabel(app.ImportBusListPanel);
            app.EnterIPEditField_2Label.Text = 'Enter IP';
            app.EnterIPEditField_2 = uieditfield(app.ImportBusListPanel, 'text');
            app.EnterIPEditField_2.Value = '0.0.0.0:0000';
            app.LoadfileButton_2 = uibutton(app.ImportBusListPanel, 'push');
            app.LoadfileButton_2.ButtonPushedFcn = createCallbackFcn(app, @OpenCSV, true);
            app.LoadfileButton_2.Text = 'Load file';
            app.OrLabel_2 = uilabel(app.ImportBusListPanel);
            app.OrLabel_2.Text = 'Or';
            app.UITable_2 = uitable(app.ImportTab);
            app.UITable_2.ColumnName = {'Bus ID'; 'Arrival'; 'Start Charging'; 'Finish Charging'};
            app.UITable_2.RowName = {};
            app.ScheduleButton_2 = uibutton(app.ImportTab, 'push');
            app.ScheduleButton_2.Text = 'Schedule';
            app.OptimiserTab = uitab(app.TabGroup);
            app.OptimiserTab.Title = 'Optimiser';
            app.OptimiseChargersPanel = uipanel(app.OptimiserTab);
            app.OptimiseChargersPanel.Title = 'Optimise Chargers';
            app.ParameterDropDownLabel = uilabel(app.OptimiseChargersPanel);
            app.ParameterDropDownLabel.Text = 'Parameter';
            app.ParameterDropDown = uidropdown(app.OptimiseChargersPanel);
            app.ParameterDropDown.Items = {'Nr Of Chargers', 'Power Per Charger'};
            app.ParameterDropDown.Value = 'Nr Of Chargers';
            app.MinEditFieldLabel = uilabel(app.OptimiseChargersPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.OptimiseChargersPanel, 'numeric');
            app.MaxEditFieldLabel = uilabel(app.OptimiseChargersPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.OptimiseChargersPanel, 'numeric');
            app.OptimiseBatteryPanel = uipanel(app.OptimiserTab);
            app.OptimiseBatteryPanel.Title = 'Optimise Battery';
            app.ParameterDropDown_2Label = uilabel(app.OptimiseBatteryPanel);
            app.ParameterDropDown_2Label.Text = 'Parameter';
            app.ParameterDropDown_2 = uidropdown(app.OptimiseBatteryPanel);
            app.ParameterDropDown_2.Items = {'Nr Of Parallel Cells', 'Nr Of Serie Cells', 'Capacity'};
            app.ParameterDropDown_2.Value = 'Nr Of Parallel Cells';
            app.MinEditField_2Label = uilabel(app.OptimiseBatteryPanel);
            app.MinEditField_2Label.Text = 'Min';
            app.MinEditField_2 = uieditfield(app.OptimiseBatteryPanel, 'numeric');
            app.MaxEditField_2Label = uilabel(app.OptimiseBatteryPanel);
            app.MaxEditField_2Label.Text = 'Max';
            app.MaxEditField_2 = uieditfield(app.OptimiseBatteryPanel, 'numeric');
            app.OptimiseBusPanel = uipanel(app.OptimiserTab);
            app.OptimiseBusPanel.Title = 'Optimise Bus';
            app.ParameterDropDown_3Label = uilabel(app.OptimiseBusPanel);
            app.ParameterDropDown_3Label.Text = 'Parameter';
            app.ParameterDropDown_3 = uidropdown(app.OptimiseBusPanel);
            app.ParameterDropDown_3.Items = {'Arrival Time'};
            app.ParameterDropDown_3.Value = 'Arrival Time';
            app.MinEditField_3Label = uilabel(app.OptimiseBusPanel);
            app.MinEditField_3Label.Text = 'Min';
            app.MinEditField_3 = uieditfield(app.OptimiseBusPanel, 'numeric');
            app.MaxEditField_3Label = uilabel(app.OptimiseBusPanel);
            app.MaxEditField_3Label.Text = 'Max';
            app.MaxEditField_3 = uieditfield(app.OptimiseBusPanel, 'numeric');
            app.Panel = uipanel(app.OptimiserTab);
            app.ChargersCheckBox = uicheckbox(app.Panel);
            app.ChargersCheckBox.Text = 'Chargers';
            app.BatteryCheckBox = uicheckbox(app.Panel);
            app.BatteryCheckBox.Text = 'Battery';
            app.BusCheckBox = uicheckbox(app.Panel);
            app.BusCheckBox.Text = 'Bus';
            app.OptimiseButton = uibutton(app.Panel, 'push');
            app.OptimiseButton.Text = 'Optimise';
            app.SimulatorTab = uitab(app.TabGroup);
            app.SimulatorTab.Title = 'Simulator';
            app.BatteryParametersPanel = uipanel(app.SimulatorTab);
            app.BatteryParametersPanel.Title = 'Battery Parameters';
            app.CapacitykAhEditFieldLabel = uilabel(app.BatteryParametersPanel);
            app.CapacitykAhEditFieldLabel.Text = 'Capacity [kAh]';
            app.CapacitykAhEditField = uieditfield(app.BatteryParametersPanel, 'numeric');
            app.CapacitykAhEditField.ValueChangedFcn = createCallbackFcn(app, @Set_SimCapacity, true);
            app.TemperatureCEditFieldLabel = uilabel(app.BatteryParametersPanel);
            app.TemperatureCEditFieldLabel.Text = 'Temperature [°C]';
            app.TemperatureCEditField = uieditfield(app.BatteryParametersPanel, 'numeric');
            app.TemperatureCEditField.ValueChangedFcn = createCallbackFcn(app, @Set_SimTemperature, true);
            app.NrOfParallelCellsEditFieldLabel = uilabel(app.BatteryParametersPanel);
            app.NrOfParallelCellsEditFieldLabel.Text = 'Nr Of Parallel Cells';
            app.NrOfParallelCellsEditField = uieditfield(app.BatteryParametersPanel, 'numeric');
            app.NrOfParallelCellsEditField.ValueChangedFcn = createCallbackFcn(app, @Set_SimNrPllCells, true);
            app.NrOfSerieCellsEditFieldLabel = uilabel(app.BatteryParametersPanel);
            app.NrOfSerieCellsEditFieldLabel.Text = 'Nr Of Serie Cells';
            app.NrOfSerieCellsEditField = uieditfield(app.BatteryParametersPanel, 'numeric');
            app.NrOfSerieCellsEditField.ValueChangedFcn = createCallbackFcn(app, @Set_SimNrSerCells, true);
            app.CellTypeDropDownLabel = uilabel(app.BatteryParametersPanel);
            app.CellTypeDropDownLabel.Text = 'Cell Type';
            app.CellTypeDropDown = uidropdown(app.BatteryParametersPanel);
            app.CellTypeDropDown.Items = {'LiPo', 'LTO', 'NMC'};
            app.CellTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @Set_SimCellType, true);
            app.CellTypeDropDown.Value = 'LiPo';
            app.ArrivalSOCEditFieldLabel = uilabel(app.BatteryParametersPanel);
            app.ArrivalSOCEditFieldLabel.Text = 'Arrival SOC [%]';
            app.ArrivalSOCEditField = uieditfield(app.BatteryParametersPanel, 'numeric');
            app.ArrivalSOCEditField.ValueChangedFcn = createCallbackFcn(app, @Set_SimStartSOC, true);
            app.DepartureSOCEditFieldLabel = uilabel(app.BatteryParametersPanel);
            app.DepartureSOCEditFieldLabel.Text = 'Departure SOC[%]';
            app.DepartureSOCEditField = uieditfield(app.BatteryParametersPanel, 'numeric');
            app.DepartureSOCEditField.ValueChangedFcn = createCallbackFcn(app, @Set_SimEndSOC, true);
            app.UIAxes = uiaxes(app.SimulatorTab);
            title(app.UIAxes, 'Power')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Power')
            app.SimulationParametersPanel = uipanel(app.SimulatorTab);
            app.SimulationParametersPanel.Title = 'Simulation Parameters';
            app.StepSliderLabel = uilabel(app.SimulationParametersPanel);
            app.StepSliderLabel.Text = 'Step';
            app.StepSlider = uislider(app.SimulationParametersPanel);
            app.StepSlider.Limits = [0 30];
            app.StepSlider.ValueChangedFcn = createCallbackFcn(app, @Set_SimStep, true);
            app.StepSlider.ValueChangingFcn = createCallbackFcn(app, @Set_SimStepLabel, true);
            app.UnitDropDownLabel = uilabel(app.SimulationParametersPanel);
            app.UnitDropDownLabel.Text = 'Unit';
            app.UnitDropDown = uidropdown(app.SimulationParametersPanel);
            app.UnitDropDown.Items = {'Hours', 'Minutes', 'Secounds'};
            app.UnitDropDown.ValueChangedFcn = createCallbackFcn(app, @Set_SimUnit, true);
            app.UnitDropDown.Value = 'Secounds';
            app.RunSimulationButton = uibutton(app.SimulationParametersPanel, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @Run_Simulation, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.ChargerPowerkWEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.ChargerPowerkWEditFieldLabel.Text = 'Charger Power [kW]';
            app.ChargerPowerkWEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.ChargerPowerkWEditField.ValueChangedFcn = createCallbackFcn(app, @Set_SimP, true);
            app.EfficiencyLabel = uilabel(app.SimulationParametersPanel);
            app.EfficiencyLabel.Text = 'Efficiency[%]';
            app.EfficiencyEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.EfficiencyEditField.Limits = [0 100];
            app.EfficiencyEditField.ValueChangedFcn = createCallbackFcn(app, @Set_SimPeff, true);
            app.Label = uilabel(app.SimulatorTab);
            app.Label.Text = '0';
            app.ExternPlotSwitchLabel = uilabel(app.EBusUIFigure);
            app.ExternPlotSwitchLabel.Text = 'Extern Plot';
            app.ExternPlotSwitch = uiswitch(app.EBusUIFigure, 'slider');
            app.ExternPlotSwitch.ValueChangedFcn = createCallbackFcn(app, @Set_ExternPlot, true);
            % Show the figure after all components are created
            app.EBusUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EBusUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EBusUIFigure)
        end
    end
end